classdef RepSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InputPanel                    matlab.ui.container.Panel
        FWHMxEditFieldLabel           matlab.ui.control.Label
        FWHMxEditField                matlab.ui.control.NumericEditField
        FWHMyEditFieldLabel           matlab.ui.control.Label
        FWHMyEditField                matlab.ui.control.NumericEditField
        FWHMzEditFieldLabel           matlab.ui.control.Label
        FWHMzEditField                matlab.ui.control.NumericEditField
        EstimateButton                matlab.ui.control.Button
        NumberofsubjectsLabel         matlab.ui.control.Label
        NumberofsubjectsEditField     matlab.ui.control.NumericEditField
        NumberofiterationsLabel       matlab.ui.control.Label
        NumberofiterationsEditField   matlab.ui.control.NumericEditField
        MaskLabel                     matlab.ui.control.Label
        MaskEditField                 matlab.ui.control.EditField
        puncthresholdLabel            matlab.ui.control.Label
        puncthresholdEditField        matlab.ui.control.NumericEditField
        Button                        matlab.ui.control.Button
        WeightingwidthEditFieldLabel  matlab.ui.control.Label
        WeightingwidthEditField       matlab.ui.control.NumericEditField
        WeightedthresholdCheckBox     matlab.ui.control.CheckBox
        TextArea                      matlab.ui.control.TextArea
        SaveoutputPanel               matlab.ui.container.Panel
        OutputdirEditFieldLabel       matlab.ui.control.Label
        OutputdirEditField            matlab.ui.control.EditField
        Button_2                      matlab.ui.control.Button
        OutputnameEditFieldLabel      matlab.ui.control.Label
        OutputnameEditField           matlab.ui.control.EditField
        RunButton                     matlab.ui.control.Button
        QuitButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OutputdirEditField.Value = pwd;
        end

        % Button pushed function: EstimateButton
        function EstimateButtonPushed(app, event)
            FWHM=rp_Smoothest_gui;
            
            app.FWHMxEditField.Value = FWHM(1);
            app.FWHMyEditField.Value = FWHM(2);
            app.FWHMzEditField.Value = FWHM(3);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [maskfile,path] = uigetfile('.nii', 'Select mask');
            
            app.MaskEditField.Value = fullfile(path,maskfile);
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app.UIFigure)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            path = uigetdir;
            
            app.OutputdirEditField.Value = path;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if isempty(app.MaskEditField.Value)
                msgbox('Please select a mask.');
                return
            else
                maskfile = app.MaskEditField.Value;
            end
                
            fwhm = [app.FWHMxEditField.Value app.FWHMyEditField.Value app.FWHMzEditField.Value];
            pthr = app.puncthresholdEditField.Value;
            if app.WeightedthresholdCheckBox.Value
                wwidth = app.WeightingwidthEditField.Value;
            else
                wwidth = 0;
            end
            
            nsub = app.NumberofsubjectsEditField.Value;
            iter = app.NumberofiterationsEditField.Value;
            
            outdir = app.OutputdirEditField.Value;
            outname = app.OutputnameEditField.Value;
            
            repSim_simulation(maskfile,fwhm,pthr,wwidth,nsub,iter,outdir,outname)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.FWHMxEditFieldLabel = uilabel(app.InputPanel);
            app.FWHMxEditFieldLabel.Text = 'FWHM x:';
            app.FWHMxEditField = uieditfield(app.InputPanel, 'numeric');
            app.FWHMxEditField.Value = 4;
            app.FWHMyEditFieldLabel = uilabel(app.InputPanel);
            app.FWHMyEditFieldLabel.Text = 'FWHM y:';
            app.FWHMyEditField = uieditfield(app.InputPanel, 'numeric');
            app.FWHMyEditField.Value = 4;
            app.FWHMzEditFieldLabel = uilabel(app.InputPanel);
            app.FWHMzEditFieldLabel.Text = 'FWHM z:';
            app.FWHMzEditField = uieditfield(app.InputPanel, 'numeric');
            app.FWHMzEditField.Value = 4;
            app.EstimateButton = uibutton(app.InputPanel, 'push');
            app.EstimateButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateButtonPushed, true);
            app.EstimateButton.Text = 'Estimate';
            app.NumberofsubjectsLabel = uilabel(app.InputPanel);
            app.NumberofsubjectsLabel.Text = 'Number of subjects:';
            app.NumberofsubjectsEditField = uieditfield(app.InputPanel, 'numeric');
            app.NumberofsubjectsEditField.Limits = [0 Inf];
            app.NumberofsubjectsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofsubjectsEditField.Value = 20;
            app.NumberofiterationsLabel = uilabel(app.InputPanel);
            app.NumberofiterationsLabel.Text = 'Number of iterations:';
            app.NumberofiterationsEditField = uieditfield(app.InputPanel, 'numeric');
            app.NumberofiterationsEditField.Limits = [0 Inf];
            app.NumberofiterationsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofiterationsEditField.Value = 1000;
            app.MaskLabel = uilabel(app.InputPanel);
            app.MaskLabel.Text = 'Mask:';
            app.MaskEditField = uieditfield(app.InputPanel, 'text');
            app.puncthresholdLabel = uilabel(app.InputPanel);
            app.puncthresholdLabel.Text = 'p(unc) threshold:';
            app.puncthresholdEditField = uieditfield(app.InputPanel, 'numeric');
            app.puncthresholdEditField.Limits = [0 1];
            app.puncthresholdEditField.ValueDisplayFormat = '%.3f';
            app.puncthresholdEditField.Value = 0.001;
            app.Button = uibutton(app.InputPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.WeightingwidthEditFieldLabel = uilabel(app.InputPanel);
            app.WeightingwidthEditFieldLabel.Text = 'Weighting width:';
            app.WeightingwidthEditField = uieditfield(app.InputPanel, 'numeric');
            app.WeightingwidthEditField.Limits = [0 1];
            app.WeightingwidthEditField.ValueDisplayFormat = '%.3f';
            app.WeightingwidthEditField.Value = 0.001;
            app.WeightedthresholdCheckBox = uicheckbox(app.InputPanel);
            app.WeightedthresholdCheckBox.Text = 'Weighted threshold';
            app.WeightedthresholdCheckBox.Value = true;
            app.TextArea = uitextarea(app.InputPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Weighted function:'; ''; '1 if p=<pthres'; 'exp(-(1/2)*((p-pthres)/width)^2) if p>pthres'};
            app.SaveoutputPanel = uipanel(app.UIFigure);
            app.SaveoutputPanel.Title = 'Save output';
            app.OutputdirEditFieldLabel = uilabel(app.SaveoutputPanel);
            app.OutputdirEditFieldLabel.Text = 'Output dir:';
            app.OutputdirEditField = uieditfield(app.SaveoutputPanel, 'text');
            app.Button_2 = uibutton(app.SaveoutputPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '...';
            app.OutputnameEditFieldLabel = uilabel(app.SaveoutputPanel);
            app.OutputnameEditFieldLabel.Text = 'Output name:';
            app.OutputnameEditField = uieditfield(app.SaveoutputPanel, 'text');
            app.OutputnameEditField.Value = 'RepSim';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RepSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end